classdef Game2048App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Game2048AppbyslandarerUIFigure  matlab.ui.Figure
        SquareBkgLabel                  matlab.ui.control.Label
        RestartButton                   matlab.ui.control.Button
        IntroduceLabel                  matlab.ui.control.Label
        Game2048Label                   matlab.ui.control.Label
        ScoreBkgLabel                   matlab.ui.control.Label
        BestBkgLabel                    matlab.ui.control.Label
        ScoreDataLabel                  matlab.ui.control.Label
        BestDataLabel                   matlab.ui.control.Label
        VirtualButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        drawSquareHdl % Handle Matrix
        colorList
        fontSizeList
        bestScore
        score
        gameOver
        squareMap
        
        GameOverPanel
        GameOverDataLabel
        GameOverRestartButton
        SavePicButton
    end
    
    methods (Access = private)    
        function createNewNumFcn(app)
            zerosPos=find(app.squareMap==0);
            tempPos=zerosPos(randi(length(zerosPos)));
            tempNum=randi(2)*2;
            app.squareMap(tempPos)=tempNum;
        end
        
        function drawSquareFcn(app)
            app.score=sum(sum(app.squareMap));
            app.ScoreDataLabel.Text=num2str(app.score);
            for i=1:4
                for j=1:4
                    tempNum=log(app.squareMap(i,j))/log(2);
                    tempNum(tempNum<0)=0;
                    app.colorList(tempNum+1,:);
                    set(app.drawSquareHdl(i,j),'BackgroundColor',app.colorList(tempNum+1,:)); 
                    switch 1
                        case app.squareMap(i,j)==0,set(app.drawSquareHdl(i,j),'Text','');
                        case app.squareMap(i,j)<=4&&app.squareMap(i,j)>0
                            set(app.drawSquareHdl(i,j),'Text',num2str(app.squareMap(i,j)),...
                                'FontSize',app.fontSizeList(tempNum+1),'FontColor',[0.4667 0.4314 0.3961])
                        case app.squareMap(i,j)>4
                            set(app.drawSquareHdl(i,j),'Text',num2str(app.squareMap(i,j)),...
                                'FontSize',app.fontSizeList(tempNum+1),'FontColor',[1 1 1])
                    end
                end
            end
            judgeFcn(app)
        end
        
        function tempMatrix=moveEventFcn(~,tempMatrix)
            for i=1:4
                tempArray=tempMatrix(i,:);
                tempArray(tempArray==0)=[];
                for j=1:(length(tempArray)-1)
                    if tempArray(j)==tempArray(j+1)
                        tempArray(j)=tempArray(j)+tempArray(j+1);
                        tempArray(j+1)=0;
                    end
                end
                tempArray(tempArray==0)=[];
                tempArray((length(tempArray)+1):4)=0;
                tempMatrix(i,:)=tempArray;
            end 
        end
        function judgeFcn(app)
            tempJudgeZeros=sum(sum(app.squareMap==0));
            tempJudgeRow=any(any(app.squareMap(1:3,:)==app.squareMap(2:4,:)));
            tempJudgeCol=any(any(app.squareMap(:,1:3)==app.squareMap(:,2:4)));
            if tempJudgeZeros+tempJudgeRow+tempJudgeCol==0
                app.gameOver=1;
                gameOverFcn(app)
            end
        end
        
        
        function gameOverFcn(app)
            app.SquareBkgLabel.Enable='off';
            app.RestartButton.Enable='off';
            app.IntroduceLabel.Enable='off';
            app.Game2048Label.Enable='off';
            app.ScoreBkgLabel.Enable='off';
            app.BestBkgLabel.Enable='off';
            app.ScoreDataLabel.Enable='off';
            app.BestDataLabel.Enable='off';
            for i=1:4
                for j=1:4
                    set(app.drawSquareHdl(i,j),'Enable','off');
                end
            end
            app.GameOverPanel.Visible='on';
            app.GameOverDataLabel.Visible='on';
            app.GameOverRestartButton.Visible='on';
            app.SavePicButton.Visible='on';
            app.GameOverDataLabel.Text={['Best : ',num2str(app.bestScore)];['Score : ',num2str(app.score)]};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            for i=1:4
                for j=1:4
                    app.drawSquareHdl(i,j)=uilabel(app.Game2048AppbyslandarerUIFigure);
                    set(app.drawSquareHdl(i,j),'Position',[30+(i-1)*(350/4) 30+(j-1)*(350/4) 350/4-10 350/4-10]);
                    set(app.drawSquareHdl(i,j),'BackgroundColor',[0.8039 0.7569 0.7059]);
                    set(app.drawSquareHdl(i,j),'Text','');
                    set(app.drawSquareHdl(i,j),'FontWeight','bold');
                    set(app.drawSquareHdl(i,j),'HorizontalAlignment','center');
                end
            end
            if ~exist('best.mat',"file")
                best=0;
                save best.mat best;
            end
            data=load('best.mat');
            app.bestScore=data.best;
            app.BestDataLabel.Text=num2str(app.bestScore);
            app.colorList=[ 0.8039    0.7569    0.7059
                    0.9333    0.8941    0.8549
                    0.9373    0.8784    0.8039
                    0.9608    0.6863    0.4824
                    0.9529    0.5922    0.4078
                    0.9529    0.4902    0.3725
                    0.9686    0.3686    0.2431
                    0.9255    0.8118    0.4510
                    0.9373    0.7882    0.3922
                    0.9333    0.7804    0.3216
                    0.9216    0.7686    0.2627
                    0.9255    0.7608    0.1804
                    0.9412    0.4078    0.4157
                    0.9216    0.3137    0.3451
                    0.9451    0.2549    0.2627
                    0.4392    0.7020    0.8157
                    0.3765    0.6353    0.8745
                    0.0902    0.5098    0.7843];
        app.fontSizeList=[12 32 32 32 32 32 32 32 32 32 28 28 28 28 24 24 24 20 20 20];
        app.squareMap=zeros(4,4);
        app.score=0;   
        app.gameOver=0;
        createNewNumFcn(app)
        createNewNumFcn(app)
        drawSquareFcn(app)
        %==============================================================================
        %A background of buttons and text 'Game Over'
        app.GameOverPanel=uipanel(app.Game2048AppbyslandarerUIFigure);
        app.GameOverPanel.Title='Game Over';
        app.GameOverPanel.TitlePosition='centertop';
        app.GameOverPanel.BackgroundColor=[0.98,0.97,0.93];
        app.GameOverPanel.ForegroundColor=[0.97,0.37,0.24];
        app.GameOverPanel.FontWeight='bold';
        app.GameOverPanel.Position=[0 206 320+80 200-6-20];
        app.GameOverPanel.FontSize=30;
        %
        app.GameOverDataLabel=uilabel(app.Game2048AppbyslandarerUIFigure);
        app.GameOverDataLabel.HorizontalAlignment='center';
        app.GameOverDataLabel.BackgroundColor='none';
        app.GameOverDataLabel.Text='';
        app.GameOverDataLabel.Position=[40 203+60 320+80-80 150-20-60];
        app.GameOverDataLabel.FontColor=[0.4667 0.4314 0.3961];
        app.GameOverDataLabel.FontWeight='bold';
        app.GameOverDataLabel.FontSize=17;
        
        %
        app.GameOverRestartButton=uibutton(app.Game2048AppbyslandarerUIFigure);
        app.GameOverRestartButton.Position=[320+80-80-100 203+30 100 30];
        app.GameOverRestartButton.Text='restart';
        app.GameOverRestartButton.BackgroundColor=[0.7333 0.6784 0.6275];
        app.GameOverRestartButton.FontColor=[1 1 1];
        app.GameOverRestartButton.FontWeight='bold';
        app.GameOverRestartButton.FontSize=15;
        app.GameOverRestartButton.ButtonPushedFcn=createCallbackFcn(app, @RestartFcn, true);
        
        %
        app.SavePicButton=uibutton(app.Game2048AppbyslandarerUIFigure);
        app.SavePicButton.Position=[80 203+30 100 30];
        app.SavePicButton.Text='save picture';
        app.SavePicButton.BackgroundColor=[0.7333 0.6784 0.6275];
        app.SavePicButton.FontColor=[1 1 1];
        app.SavePicButton.FontWeight='bold';
        app.SavePicButton.FontSize=15;
        app.SavePicButton.ButtonPushedFcn=createCallbackFcn(app, @SavePicFcn, true);

        app.GameOverPanel.Visible='off';
        app.GameOverDataLabel.Visible='off';
        app.GameOverRestartButton.Visible='off';
        app.SavePicButton.Visible='off';
        end

        % Key press function: Game2048AppbyslandarerUIFigure
        function KeyPressFcn(app, event)
            tempMap=app.squareMap;
            switch event.Key
                case 'uparrow'
                    tempMap=moveEventFcn(app,tempMap(:,4:-1:1));
                    tempMap=tempMap(:,4:-1:1);
                case 'downarrow'
                    tempMap=moveEventFcn(app,tempMap);
                case 'rightarrow'
                    tempMap=tempMap';
                    tempMap=moveEventFcn(app,tempMap(:,4:-1:1));
                    tempMap=tempMap(:,4:-1:1);
                    tempMap=tempMap';
                    
                case 'leftarrow'
                    tempMap=moveEventFcn(app,tempMap');
                    tempMap=tempMap';
            end
            if any(any(app.squareMap~=tempMap))
                app.squareMap=tempMap;
                createNewNumFcn(app)
                drawSquareFcn(app)
            end
        end

        % Button pushed function: RestartButton
        function RestartFcn(app, event)
            best=max([app.bestScore,app.score]);
            save best.mat best -append
            data=load('best.mat');
            app.bestScore=data.best;
            app.BestDataLabel.Text=num2str(app.bestScore);
            app.squareMap=zeros(4,4);
            app.gameOver=0;
            createNewNumFcn(app)
            createNewNumFcn(app)
            drawSquareFcn(app)
            app.SquareBkgLabel.Enable='on';
            app.RestartButton.Enable='on';
            app.IntroduceLabel.Enable='on';
            app.Game2048Label.Enable='on';
            app.ScoreBkgLabel.Enable='on';
            app.BestBkgLabel.Enable='on';
            app.ScoreDataLabel.Enable='on';
            app.BestDataLabel.Enable='on';
            for i=1:4
                for j=1:4
                    set(app.drawSquareHdl(i,j),'Enable','on');
                end
            end
            app.GameOverPanel.Visible='off';
            app.GameOverDataLabel.Visible='off';
            app.GameOverRestartButton.Visible='off';
            app.SavePicButton.Visible='off';
        end

        % Button pushed function: VirtualButton
        function SavePicFcn(app, event)
            screensize=get(0,'screensize');
            screensize=1.5*screensize;
            robot=java.awt.Robot();
            rectangle=java.awt.Rectangle();
            rectangle.x=0;
            rectangle.y=0;
            rectangle.width=screensize(3);
            rectangle.height=screensize(4);
            image=robot.createScreenCapture(rectangle);
            data=image.getData();
            temp=zeros(screensize(3)*screensize(4)*3,1);
            temp=data.getPixels(0,0,screensize(3),screensize(4),temp);
            temp=uint8(temp);
            R=temp(1:3:end);
            G=temp(2:3:end);
            B=temp(3:3:end);
            R=reshape(R,[screensize(3),screensize(4)]);
            G=reshape(G,[screensize(3),screensize(4)]);
            B=reshape(B,[screensize(3),screensize(4)]);
            R=R';
            G=G';
            B=B';
            ima=cat(3,R,G,B);
            tempPos=app.Game2048AppbyslandarerUIFigure.Position;
            xp=tempPos(1);yp=tempPos(2);xl=tempPos(3);yl=tempPos(4);
            tempIma=ima(round(yp.*1.5+20):round((yp+yl).*1.5+80),round(xp.*1.5-15):round((xp+xl).*1.5+15),:);
            [filename, pathname] = uiputfile({'*.jpg;*.png','All Image Files';...
            '*.jpg','JPG';'*.png','PNG' });
            imwrite(tempIma,[pathname,filename]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Game2048AppbyslandarerUIFigure = uifigure('Visible', 'off');
            app.Game2048AppbyslandarerUIFigure.Name = 'Game2048App | by slandarer';
            app.Game2048AppbyslandarerUIFigure.KeyPressFcn = createCallbackFcn(app, @KeyPressFcn, true);
            app.SquareBkgLabel = uilabel(app.Game2048AppbyslandarerUIFigure);
            app.SquareBkgLabel.Text = '';
            app.RestartButton = uibutton(app.Game2048AppbyslandarerUIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartFcn, true);
            app.RestartButton.Text = 'New Game';
            app.IntroduceLabel = uilabel(app.Game2048AppbyslandarerUIFigure);
            app.IntroduceLabel.Text = {' join the numbers to get'; ' higher score'};
            app.Game2048Label = uilabel(app.Game2048AppbyslandarerUIFigure);
            app.Game2048Label.Text = '2048';
            app.ScoreBkgLabel = uilabel(app.Game2048AppbyslandarerUIFigure);
            app.ScoreBkgLabel.Text = 'SCORE';
            app.BestBkgLabel = uilabel(app.Game2048AppbyslandarerUIFigure);
            app.BestBkgLabel.Text = 'BEST';
            app.ScoreDataLabel = uilabel(app.Game2048AppbyslandarerUIFigure);
            app.ScoreDataLabel.Text = '0';
            app.BestDataLabel = uilabel(app.Game2048AppbyslandarerUIFigure);
            app.BestDataLabel.Text = '0';
            app.VirtualButton = uibutton(app.Game2048AppbyslandarerUIFigure, 'push');
            app.VirtualButton.ButtonPushedFcn = createCallbackFcn(app, @SavePicFcn, true);
            app.VirtualButton.Visible = 'off';
            % Show the figure after all components are created
            app.Game2048AppbyslandarerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Game2048App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Game2048AppbyslandarerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Game2048AppbyslandarerUIFigure)
        end
    end
end